classdef classifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CoordenadaXEditFieldLabel       matlab.ui.control.Label
        CoordenadaXEditField            matlab.ui.control.NumericEditField
        CoordenadaYEditFieldLabel       matlab.ui.control.Label
        CoordenadaYEditField            matlab.ui.control.NumericEditField
        DispersinEditFieldLabel         matlab.ui.control.Label
        DispersinEditField              matlab.ui.control.NumericEditField
        NmerodeClasesEditFieldLabel     matlab.ui.control.Label
        NmerodeClasesEditField          matlab.ui.control.NumericEditField
        CrearButton                     matlab.ui.control.Button
        CrearClaseButton                matlab.ui.control.Button
        CoordenadaXPruebaEditFieldLabel  matlab.ui.control.Label
        CoordenadaXPruebaEditField      matlab.ui.control.NumericEditField
        CoordenadaYPruebaEditFieldLabel  matlab.ui.control.Label
        CoordenadaYPruebaEditField      matlab.ui.control.NumericEditField
        MtodoListBoxLabel               matlab.ui.control.Label
        MtodoListBox                    matlab.ui.control.ListBox
        SalidaListBoxLabel              matlab.ui.control.Label
        SalidaListBox                   matlab.ui.control.ListBox
        MiembrosporClaseEditFieldLabel  matlab.ui.control.Label
        MiembrosporClaseEditField       matlab.ui.control.NumericEditField
        ProbarButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        classIndex % The currently created class index. 
        members % The number of members in each class.
        dimension = 2 % The dimension of the vectors.
        testCount % The current test count.
        classes % The number of classes.
        classifiers % The classes.
        means % The class means.
        
    end
    
    methods (Access = private)
    
        function CreateClass(app, k, x, y, r)
            classMembers = randn(app.dimension, app.members) .* r;
            classMembers(1, :) = classMembers(1, :) + x;
            classMembers(2, :) = classMembers(2, :) + y;
            app.classifiers(:, :, k) = classMembers;
            app.means(:, k) = mean(app.classifiers(:, :, k), 2);
            scatter(app.UIAxes, app.classifiers(1, :, k), app.classifiers(2, :, k), 'filled', 'DisplayName', strcat('C', num2str(k)));
        end
    
        function TestVector(app, x, y)
            option = str2num(app.MtodoListBox.Value);
            testVector = [ x ; y ];
            minClass = 0;
            if option == 1
                minClass = app.Euclidean(testVector);
            elseif option == 2
                minClass = app.Mahalanobis(testVector);
            end       
            scatter(app.UIAxes, x, y, 'LineWidth', 1.5, 'DisplayName', strcat('X', num2str(app.testCount))); 
            app.testCount = app.testCount + 1;
            app.PrintOutput(num2str(minClass));
        end
    
        function minClass = Mahalanobis(app, testVector)
            % Minimum Mahalanobis classification algorithm.
            
            % Collect sizes.
            [ dc, elements, app.classes ] = size(app.classifiers);
            [ dt, ~ ] = size(testVector);
            [ dm, ~ ] = size(app.means); 
            
            minClass = 0;
            
            if dc ~= dm
                return
            end
            
            if dc ~= dt
                return
            end
            
            sigma = zeros(dc, dc, app.classes);
            
            for k = 1 : app.classes
                m = app.classifiers(:, :, k) - app.means(:, k);
                sigma(:, :, k) = (m * m') ./ elements;
            end
            
            % Minimal distance starts at infinity.
            minDist = Inf;
            % Pick any class as the closest class.
            minClass = 1;
            
            for k = 1 : app.classes
                % Compute Mahalanobis distance.
                m = testVector - app.means(:, k);
                dist = (m' / sigma(:, :, k)) * m;
                if minDist > dist
                    % Minimize distance.
                    minDist = dist;
                    % Store the new closest class.
                    minClass = k;
                end
            end
            
        end
    
        function minClass = Euclidean(app, testVector)
            % Minimum Distance classification algorithm.
            
            % Collect sizes.
            [ dc, ~, app.classes ] = size(app.classifiers);
            [ dt, ~ ] = size(testVector);
            [ dm, ~ ] = size(app.means); 
            
            minClass = 0;
            
            if dc ~= dm
                return
            end
            
            if dc ~= dt
                return
            end
            
            % Minimal distance starts at infinity.
            minDist = Inf;
            % Pick any class as the closest class.
            minClass = 1;
            for k = 1 : app.classes
                % Compute distance to class mean.
                dist = norm(app.means(:, k) - testVector);
                if minDist > dist
                    % Minimize distance.
                    minDist = dist;
                    % Store the new closest class.
                    minClass = k;
                end
            end
            
        end
        
        function DisableTestGroup(app)
            app.CoordenadaXPruebaEditField.Enable = 'off';
            app.CoordenadaYPruebaEditField.Enable = 'off';
            app.ProbarButton.Enable = 'off';
        end
        
        function EnableTestGroup(app)
            app.CoordenadaXPruebaEditField.Enable = 'on';
            app.CoordenadaYPruebaEditField.Enable = 'on';
            app.ProbarButton.Enable = 'on';
        end
        
        function DisableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'off';
            app.NmerodeClasesEditField.Enable = 'off';
            app.CrearButton.Enable = 'off';
        end
        
        function EnableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'on';
            app.NmerodeClasesEditField.Enable = 'on';
            app.CrearButton.Enable = 'on';
        end
        
        function DisableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditField.Enable = 'off';
            app.DispersinEditField.Enable = 'off';
            app.CrearClaseButton.Enable = 'off';
        end
        
        function EnableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'on';
            app.CoordenadaYEditField.Enable = 'on';
            app.DispersinEditField.Enable = 'on';
            app.CrearClaseButton.Enable = 'on';
        end
    
        function PrintOutput(app, output)
            %data = get(app.SalidaListBox, 'String');
            %set(app.SalidaListBox, 'String', [ cellstr(output), data ]);
        end
    
    end
    

    methods (Access = private)

        % Button pushed function: CrearButton
        function CrearButtonPushed(app, event)
            app.members = app.MiembrosporClaseEditField.Value;
            app.classes = app.NmerodeClasesEditField.Value;
            app.classifiers = zeros(app.dimension, app.members, app.classes);
            app.means = zeros(app.dimension, app.classes);
            app.DisableClassesGroup();
            app.EnableClassGroup();
            app.classIndex = 1;
            app.testCount = 1;
            cla(app.UIAxes);
        end

        % Button pushed function: CrearClaseButton
        function CrearClaseButtonPushed(app, event)
            x = app.CoordenadaXEditField.Value;
            y = app.CoordenadaYEditField.Value;
            r = app.DispersinEditField.Value;
            app.CreateClass(app.classIndex, x, y, r);
            app.classIndex = app.classIndex + 1;
            if app.classIndex > app.classes
                scatter(app.UIAxes, app.means(1, :), app.means(2, :), 'filled', 'DisplayName', 'Medias');
                legend(app.UIAxes, 'show');
                app.EnableClassesGroup();
                app.DisableClassGroup();
                app.EnableTestGroup();
                return;
            end
        end

        % Button pushed function: ProbarButton
        function ProbarButtonPushed(app, event)
            x = app.CoordenadaXPruebaEditField.Value;
            y = app.CoordenadaYPruebaEditField.Value;
            app.TestVector(x, y);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'App de Clasificadores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CoordenadaXEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaXEditFieldLabel.Text = 'Coordenada X';
            app.CoordenadaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaYEditFieldLabel.Text = 'Coordenada Y';
            app.CoordenadaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaYEditField.Enable = 'off';
            app.DispersinEditFieldLabel = uilabel(app.UIFigure);
            app.DispersinEditFieldLabel.Text = 'Dispersión';
            app.DispersinEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispersinEditField.Limits = [0 Inf];
            app.DispersinEditField.Enable = 'off';
            app.NmerodeClasesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeClasesEditFieldLabel.Text = 'Número de Clases';
            app.NmerodeClasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodeClasesEditField.Limits = [1 Inf];
            app.NmerodeClasesEditField.RoundFractionalValues = 'on';
            app.NmerodeClasesEditField.Value = 1;
            app.CrearButton = uibutton(app.UIFigure, 'push');
            app.CrearButton.ButtonPushedFcn = createCallbackFcn(app, @CrearButtonPushed, true);
            app.CrearButton.Text = 'Crear';
            app.CrearClaseButton = uibutton(app.UIFigure, 'push');
            app.CrearClaseButton.ButtonPushedFcn = createCallbackFcn(app, @CrearClaseButtonPushed, true);
            app.CrearClaseButton.Enable = 'off';
            app.CrearClaseButton.Text = 'Crear Clase';
            app.CoordenadaXPruebaEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaXPruebaEditFieldLabel.Text = 'Coordenada X Prueba';
            app.CoordenadaXPruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaXPruebaEditField.Enable = 'off';
            app.CoordenadaYPruebaEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaYPruebaEditFieldLabel.Text = 'Coordenada Y Prueba';
            app.CoordenadaYPruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaYPruebaEditField.Enable = 'off';
            app.MtodoListBoxLabel = uilabel(app.UIFigure);
            app.MtodoListBoxLabel.Text = 'Método';
            app.MtodoListBox = uilistbox(app.UIFigure);
            app.MtodoListBox.Items = {'Distancia Euclideana', 'Mahalanobis'};
            app.MtodoListBox.ItemsData = {'1', '2'};
            app.MtodoListBox.Value = '1';
            app.SalidaListBoxLabel = uilabel(app.UIFigure);
            app.SalidaListBoxLabel.Text = 'Salida';
            app.SalidaListBox = uilistbox(app.UIFigure);
            app.SalidaListBox.Items = {};
            app.SalidaListBox.Value = {};
            app.MiembrosporClaseEditFieldLabel = uilabel(app.UIFigure);
            app.MiembrosporClaseEditFieldLabel.Text = 'Miembros por Clase';
            app.MiembrosporClaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiembrosporClaseEditField.Limits = [1 Inf];
            app.MiembrosporClaseEditField.RoundFractionalValues = 'on';
            app.MiembrosporClaseEditField.Value = 1;
            app.ProbarButton = uibutton(app.UIFigure, 'push');
            app.ProbarButton.ButtonPushedFcn = createCallbackFcn(app, @ProbarButtonPushed, true);
            app.ProbarButton.Enable = 'off';
            app.ProbarButton.Text = 'Probar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = classifierApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end